classdef ephysExplorer2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EphysExplorerUIFigure       matlab.ui.Figure
        spectrogramSlider           matlab.ui.control.Slider
        regionSelectorPanel         matlab.ui.container.Panel
        regionAllButton             matlab.ui.control.Button
        regionClearButton           matlab.ui.control.Button
        regionFilterButton          matlab.ui.control.Button
        box_L2a                     matlab.ui.control.CheckBox
        box_L2b                     matlab.ui.control.CheckBox
        box_L                       matlab.ui.control.CheckBox
        box_L3                      matlab.ui.control.CheckBox
        box_L1                      matlab.ui.control.CheckBox
        box_CSt                     matlab.ui.control.CheckBox
        box_NCM                     matlab.ui.control.CheckBox
        box_NCL                     matlab.ui.control.CheckBox
        box_CMM                     matlab.ui.control.CheckBox
        box_CLM                     matlab.ui.control.CheckBox
        box_HVC                     matlab.ui.control.CheckBox
        ShowRegionsButton           matlab.ui.control.StateButton
        FocusPlusButton             matlab.ui.control.Button
        FocusMinusButton            matlab.ui.control.Button
        FocusCoordsLabel            matlab.ui.control.Label
        FocusButton                 matlab.ui.control.StateButton
        StimulusPanel               matlab.ui.container.Panel
        stimulusDropdown            matlab.ui.control.DropDown
        subjectsPanel               matlab.ui.container.Panel
        subjectListBox              matlab.ui.control.ListBox
        cellTypeSelectorPanel       matlab.ui.container.Panel
        FastSpikersCheckBox         matlab.ui.control.CheckBox
        RegSpikersCheckBox          matlab.ui.control.CheckBox
        FSIconDropDown              matlab.ui.control.DropDown
        RSIconDropDown              matlab.ui.control.DropDown
        DisplayOptionsPanel         matlab.ui.container.Panel
        PlaybackMultiplierLabel     matlab.ui.control.Label
        playbackMultiplierField     matlab.ui.control.NumericEditField
        ColorMapLabel               matlab.ui.control.Label
        colormapDropDown            matlab.ui.control.DropDown
        PointTypeDropDownLabel      matlab.ui.control.Label
        PointTypeDropDown           matlab.ui.control.DropDown
        ModeDropDownLabel           matlab.ui.control.Label
        ModeDropDown                matlab.ui.control.DropDown
        MaxPointSizeEditFieldLabel  matlab.ui.control.Label
        MaxPointSizeEditField       matlab.ui.control.NumericEditField
        rippleTuningPanel           matlab.ui.container.Panel
        RippleDropDown              matlab.ui.control.DropDown
        NoiseDropDown               matlab.ui.control.DropDown
        SongDropDown                matlab.ui.control.DropDown
        UnitDropDown                matlab.ui.control.DropDown
        RippleResponseLabel         matlab.ui.control.Label
        NoiseResponseLabel          matlab.ui.control.Label
        mraAxes                     matlab.ui.control.UIAxes
        nraAxes                     matlab.ui.control.UIAxes
        apAxes                      matlab.ui.control.UIAxes
        DisplayTuningMetricsPanel   matlab.ui.container.Panel
        TuningMetricsDropDown       matlab.ui.control.DropDown
        PlotTuningButton            matlab.ui.control.Button
        AnimatePSTHPanel            matlab.ui.container.Panel
        ResetButton                 matlab.ui.control.Button
        PlayButton                  matlab.ui.control.StateButton
        UnitTable                   matlab.ui.control.Table
        ExportVideoPanel            matlab.ui.container.Panel
        WriteFramesButton           matlab.ui.control.Button
        FPSEditFieldLabel           matlab.ui.control.Label
        FPSEditField                matlab.ui.control.NumericEditField
        StitchVideoButton           matlab.ui.control.Button
        TogglePeaksButton           matlab.ui.control.StateButton
        TogglePPSTHButton           matlab.ui.control.StateButton
        LoadDatasetPanel            matlab.ui.container.Panel
        DatasetDropdown             matlab.ui.control.DropDown
        animatedAxes                matlab.ui.control.UIAxes
        mpsAxes                     matlab.ui.control.UIAxes
        spectrogramAxes             matlab.ui.control.UIAxes
        fpsAxes                     matlab.ui.control.UIAxes
    end

    
    %%%%================================================================%%%
    %%%%================================================================%%%
    %                                                                   |||
    %                                                                   |||
    %   %%%         TO DO LIST            %%%                           |||
    %                                                                   |||
    %   %%% ======= HIGH PRIORITY ======= %%%                           |||
    %   % Add checks for BOS/TUT/DUET stims available by subj selection
    %   % Add song sylFR spider plot to tuning metrics panel
    %   % Separate markers for FS/RS
    %   % Fix single unit metrics axes color when switch to dark mode
    %       % no response label should be good - test
    %
    %
    %   %%% ======= LOW PRIORITY ======== %%%
    %   % Add axis labels to MPS
    %                                                                   |||
    %                                                                   |||
    %%%%================================================================%%%
    %%%%================================================================%%%


    properties (Access = private)
        % External data
        unitInfo % Unit data - xyz coords, region, cell type, etc
        stimInfo % Stimulus data - including spectrograms
        songPSTH % Song response data - PSTH's for all song stims
        MRA % Ripple response data
        NRA % Noise burst response data
        FRA % Tone response data
        
        % Timer
        Timer timer
        
        % Convenience data
        subjectList
        selection % index into stimInfo from current stim selection
        unitIdxs % index for selected units into unitInfo, songPSTH, MRA, NRA
        step % indicator for current time step
        cellTypePalette = {[0.3010 0.7450 0.9330], [0.6350 0.0780 0.1840]};
        cmapPalette % load in Jacob's custom colormaps
        schematicData
        schematicSlice
        
        fsIdxs % indices into unitInfo for cell types
        rsIdxs
        rowTypes % indices into rows of PSTH matrix for cell types
        
        pointType
        sizeColorVector
        maxPointSize
        
        jitterRange = [-0.1 0.1];
        jitter
        
        stepLength;
        rangeWinStart;
        rangeWinEnd;
        
        % Plot objects for animating
        fpsLine
        specLine
        specPeakIndicator
        specPPSTH
        rangeWinL
        rangeWinPatch
        rangeWinR
        neuronScatter
        neuronScatter2
        regionSchematic = cell(4, 1);
        currentPsthMat
        currentMarkedCell
    end
    
    methods (Access = private)
    
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Update Stimulus Selection Listbox
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function updateStimList(app)
            % Check current bird ID's selected
            
            % Check unique stims available for current selection, and display those
            
            % Update dropdown items
            app.stimulusDropdown.Items = {app.stimInfo.name_formatted};
        end
        
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Update Stimulus Selection
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function updateStimSelection(app)
            % Collect selection from dropdown
            
            app.selection = find(strcmp({app.stimInfo.name_formatted}, app.stimulusDropdown.Value));
            
            % Update spectrogram
            app.stepLength = size(app.stimInfo(app.selection).S, 2);
            imagesc(app.spectrogramAxes, app.stimInfo(app.selection).S, 'HitTest', 'off');
                app.spectrogramAxes.XLim = [0, size(app.stimInfo(app.selection).S, 2)];
                app.spectrogramAxes.YLim = [0, size(app.stimInfo(app.selection).S, 1)];
                app.spectrogramAxes.YDir = 'normal';
            app.specLine = line(app.spectrogramAxes, [app.step app.step], [1 100], 'LineWidth', 2, 'Color', [0.8 0.4 0.4], 'HitTest', 'off');
            
            % Update MPS
            imagesc(app.mpsAxes, app.stimInfo(app.selection).MPS);
                app.mpsAxes.XLim = [0, size(app.stimInfo(app.selection).MPS, 2)];
                app.mpsAxes.YLim = [0, size(app.stimInfo(app.selection).MPS, 1)];
                app.mpsAxes.YDir = 'normal';
                
            % Update FPS
            switch app.ModeDropDown.Value
                case 'light'
                    fpsCol = 'k';
                case 'dark'
                    fpsCol = 'w';
            end
            app.fpsLine = plot(app.fpsAxes, app.stimInfo(app.selection).FPS ./ max(app.stimInfo(app.selection).FPS, [], 'all'), ...
                'LineStyle', '-', 'Color', fpsCol);
%             app.fpsLine = animatedline(app.fpsAxes, ...
%                 1:length(app.stimInfo(app.selection).FPS), ...
%                 app.stimInfo(app.selection).FPS ./ max(app.stimInfo(app.selection).FPS, [], 'all'), ...
%                 'LineStyle', '-', 'Color', 'k');
            
        end
        
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Update 3D Scatter Plot
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function create3Dscatter(app)
            
            app.checkSizeColorMode();
            
            app.fsIdxs = app.getTypeIdxs('FS');
            app.rsIdxs = app.getTypeIdxs('RS');
            app.rowTypes = {app.unitInfo(app.unitIdxs).cellType};
            [fsMarker, rsMarker] = app.getCellTypeMarkers();
            
            switch app.pointType
                case 'color'
                    hold(app.animatedAxes, 'on');
                    app.neuronScatter = scatter3(app.animatedAxes, [app.unitInfo(app.fsIdxs).X] + app.jitter(app.fsIdxs,1)', [app.unitInfo(app.fsIdxs).Y], [app.unitInfo(app.fsIdxs).Z], ...
                        1 + app.maxPointSize, app.currentPsthMat(strcmp(app.rowTypes,'FS'),app.step), 'filled', 'Clipping', 'off', 'Marker', fsMarker);
                    app.neuronScatter2 = scatter3(app.animatedAxes, [app.unitInfo(app.rsIdxs).X] + app.jitter(app.rsIdxs,1)', [app.unitInfo(app.rsIdxs).Y], [app.unitInfo(app.rsIdxs).Z], ...
                        1 + app.maxPointSize, app.currentPsthMat(strcmp(app.rowTypes,'RS'),app.step), 'filled', 'Clipping', 'off', 'Marker', rsMarker);
                    hold(app.animatedAxes, 'off');
                case 'size'
                    app.neuronScatter = scatter3(app.animatedAxes, [app.unitInfo(app.fsIdxs).X] + app.jitter(app.fsIdxs,1)', [app.unitInfo(app.fsIdxs).Y], [app.unitInfo(app.fsIdxs).Z], ...
                        1 + app.currentPsthMat(strcmp(app.rowTypes,'FS'),app.step) * app.maxPointSize, app.sizeColorVector, 'filled', 'Clipping', 'off', 'Marker', fsMarker);
                    app.neuronScatter2 = scatter3(app.animatedAxes, [app.unitInfo(app.rsIdxs).X] + app.jitter(app.rsIdxs,1)', [app.unitInfo(app.rsIdxs).Y], [app.unitInfo(app.rsIdxs).Z], ...
                        1 + app.currentPsthMat(strcmp(app.rowTypes,'RS'),app.step) * app.maxPointSize, app.sizeColorVector, 'filled', 'Clipping', 'off', 'Marker', rsMarker);
            end
            
            
            caxis(app.animatedAxes, [0 1]);
            set(app.animatedAxes, ...
                'CameraViewAngleMode','manual', ...
                'Clipping','off', ...
                'Projection','perspective', ...
                'Interactions',[rotateInteraction zoomInteraction], ...
                'XLim', [-2, 2], ...
                'XTick', [-1, 0, 1], ...
                'XTickLabel', {'Left', '\lambda', 'Right'}, ...
                'YTick', [-1, 0, 2], ...
                'YLim', [0, 2], ...
                'YTick', [0, 0.5, 1, 1.5, 2], ...
                'YTickLabel', {'Caudal', '.5', '1', '1.5', 'Rostral'}, ...
                'ZLim', [-2.5, 0.5], ...
                'ZTick', [-2, -1, 0], ...
                'ZTickLabel', {'2', '1', 'Brain Surface'}, ...
                'TickLength', [0,0], ...
                'PlotBoxAspectRatio', [0.5, 0.5, 0.5]);
            set(app.neuronScatter, 'Clipping', 'off');
            set(app.neuronScatter2, 'Clipping', 'off');
            %app.neuronScatter.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Bird', {app.unitInfo(app.unitIdxs).birdID}); % should I read values from UnitTable here?
            %app.neuronScatter.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Block', [app.unitInfo(app.unitIdxs).block]);
            %app.neuronScatter.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Channel', [app.unitInfo(app.unitIdxs).ch]);
            %app.neuronScatter.DataTipTemplate.DataTipRows(4) = dataTipTextRow('Unit', [app.unitInfo(app.unitIdxs).unit]);
            %app.neuronScatter.DataTipTemplate.DataTipRows(5) = dataTipTextRow('Region', {app.unitInfo(app.unitIdxs).region});
            %app.neuronScatter.DataTipTemplate.DataTipRows(6) = dataTipTextRow('Type', {app.unitInfo(app.unitIdxs).cellType});
            
            enableDefaultInteractivity(app.animatedAxes);
            %daspect(app.animatedAxes, [0.6 0.8 1]); % original from EE
            daspect(app.animatedAxes, [1 1 1]);
            
        end
        
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Update 3D Scatter Plot
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function update3Dscatter(app)
            
            app.checkSizeColorMode();
            
            app.fsIdxs = app.getTypeIdxs('FS');
            app.rsIdxs = app.getTypeIdxs('RS');
            app.rowTypes = {app.unitInfo(app.unitIdxs).cellType};
            [fsMarker, rsMarker] = app.getCellTypeMarkers();
            
            switch app.pointType
                case 'color'
                    set(app.neuronScatter, ...
                        'XData', [app.unitInfo(app.fsIdxs).X] + app.jitter(app.fsIdxs,1)', ...
                        'YData', [app.unitInfo(app.fsIdxs).Y], ...
                        'ZData', [app.unitInfo(app.fsIdxs).Z], ...
                        'SizeData', 1 + app.maxPointSize, ...
                        'CData', app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step), ...
                        'Marker', fsMarker);
                    set(app.neuronScatter2, ...
                        'XData', [app.unitInfo(app.rsIdxs).X] + app.jitter(app.rsIdxs,1)', ...
                        'YData', [app.unitInfo(app.rsIdxs).Y], ...
                        'ZData', [app.unitInfo(app.rsIdxs).Z], ...
                        'SizeData', 1 + app.maxPointSize, ...
                        'CData', app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step), ...
                        'Marker', rsMarker);
                case 'size'
                    set(app.neuronScatter, ...
                        'XData', [app.unitInfo(app.fsIdxs).X] + app.jitter(app.fsIdxs,1)', ...
                        'YData', [app.unitInfo(app.fsIdxs).Y], ...
                        'ZData', [app.unitInfo(app.fsIdxs).Z], ...
                        'SizeData', 1 + app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step) * app.maxPointSize, ...
                        'CData', app.sizeColorVector, ...
                        'Marker', fsMarker);
                    set(app.neuronScatter2, ...
                        'XData', [app.unitInfo(app.rsIdxs).X] + app.jitter(app.rsIdxs,1)', ...
                        'YData', [app.unitInfo(app.rsIdxs).Y], ...
                        'ZData', [app.unitInfo(app.rsIdxs).Z], ...
                        'SizeData', 1 + app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step) * app.maxPointSize, ...
                        'CData', app.sizeColorVector, ...
                        'Marker', rsMarker);
            end
        end
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Animate Plots
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function animatePlots(app, ~, ~)
            
            set(app.spectrogramSlider, 'Value', app.step);
            % update the FPS
            set(app.fpsLine, 'YData', app.stimInfo(app.selection).S(:, app.step));
            % update the step indicator line 
            set(app.specLine, 'XData', [app.step app.step]);
            % update the PTSH per cell
            switch app.pointType
                case 'color'
                    set(app.neuronScatter, 'CData', app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step));
                    set(app.neuronScatter2, 'CData', app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step));
                case 'size'
                    set(app.neuronScatter, 'SizeData', 1 + app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step) * app.maxPointSize);
                    set(app.neuronScatter2, 'SizeData', 1 + app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step) * app.maxPointSize);
            end
            
            drawnow % must include otherwise frames are skipped
            
            increment = app.step + app.playbackMultiplierField.Value;
            if increment > app.rangeWinEnd-1
                app.step = app.rangeWinStart;
            else 
                app.step = increment;
            end
        end
        
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Update Unit Table
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function updateUnitTable(app)
            rnames = find(app.unitIdxs);
            app.UnitTable.Data = table({app.unitInfo(app.unitIdxs).birdID}', ...
                [app.unitInfo(app.unitIdxs).block]', ...
                [app.unitInfo(app.unitIdxs).ch]', ...
                [app.unitInfo(app.unitIdxs).unit]', ...
                {app.unitInfo(app.unitIdxs).region}', ...
                {app.unitInfo(app.unitIdxs).cellType}');
            app.UnitTable.RowName = strsplit(num2str(rnames), ' ')';
        end
        
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Update PSTH Matrix
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~        
        function updatePsthMatrix(app)
            
            %dbstop if error
            % Check cell type and region checkboxes for filtering
            TYPES = {};
            if app.RegSpikersCheckBox.Value; TYPES{end+1} = 'RS'; end
            if app.FastSpikersCheckBox.Value; TYPES{end+1} = 'FS'; end
            
            REGIONS = {};
            if app.box_L2a.Value; REGIONS{end+1} = 'L2a'; end
            if app.box_L2b.Value; REGIONS{end+1} = 'L2b'; end
            if app.box_L.Value; REGIONS{end+1} = 'L'; end
            if app.box_L3.Value; REGIONS{end+1} = 'L3'; end
            if app.box_L1.Value; REGIONS{end+1} = 'L1'; end
            if app.box_CSt.Value; REGIONS{end+1} = 'CSt'; end
            if app.box_NCM.Value
                REGIONS{end+1} = 'NCM';
                REGIONS{end+1} = 'dNCM';
                REGIONS{end+1} = 'vNCM';
            end
            if app.box_NCL.Value
                REGIONS{end+1} = 'NCL';
                REGIONS{end+1} = 'dNCL';
                REGIONS{end+1} = 'vNCL';
            end
            if app.box_CMM.Value; REGIONS{end+1} = 'CMM'; end
            if app.box_CLM.Value; REGIONS{end+1} = 'CLM'; end
            if app.box_HVC.Value; REGIONS{end+1} = 'HVC'; end

            
            % Filter units
            birdnames = split(app.subjectListBox.Value,' ');
            if length(size(birdnames)) > 2
                birdnames = birdnames(:,:,2);
            else
                birdnames = birdnames{2};
            end
            
            app.unitIdxs = ismember({app.unitInfo.birdID}, birdnames) ...
                & ismember({app.unitInfo.cellType}, TYPES) ...
                & ismember({app.unitInfo.region}, REGIONS);
            
            units = find(app.unitIdxs);
            
            if isempty(units)
                app.currentPsthMat = nan(1,5000);
            else
                notnan = find(~isnan([app.songPSTH(units).mn_all]));
                if isempty(notnan)
                    notnan = 1;
                else
                    notnan = notnan(1);
                end
                
                if contains(app.DatasetDropdown.Value, 'Moore')
                    songIdx = app.selection;
                else
                    songIdx = find(strcmp(app.songPSTH(units(notnan)).song, app.stimInfo(app.selection).name));
                end
                
                psth_mat = zeros(length(units), size(app.songPSTH(units(notnan)).psth{songIdx}, 2));
                for i = 1:size(psth_mat, 1)
                    % get the index corresponding to the right stim
                    if contains(app.DatasetDropdown.Value, 'Moore')
                        songIdx = app.selection;
                    else
                        songIdx = find(strcmp(app.songPSTH(units(i)).song, app.stimInfo(app.selection).name));
                    end
                    if ~isempty(songIdx) % make sure that unit heard that stim
                        % standardize by dividing by SD to all songs, but don't subtract mean bc don't want negative values
                        % so psth's are standardized but not z-scored
                        psth = app.songPSTH(units(i)).psth{songIdx};
                        psth_mat(i,:) = psth / app.songPSTH(units(i)).sd_all;
                    else
                        psth_mat(i,:) = nan;
                    end
                end
                % restrict range to 0-1
                psth_mat = psth_mat ./ nanmax(psth_mat, [], 'all');
                app.currentPsthMat = psth_mat;
                app.spectrogramSlider.Limits = [1 size(psth_mat,2)];
            end
        end
        
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Check Color Mode
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~        
        function checkSizeColorMode(app)
            
            colorMode = app.ModeDropDown.Value;
            
            switch colorMode
                case 'light'
                    app.sizeColorVector = [0 0 0];
                case 'dark'
                    app.sizeColorVector = [1 1 1];
            end
            
        end

%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Update Color Maps
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function updateColorMap(app)
            switch app.colormapDropDown.Value
                case 'turbo'
                    value = app.cmapPalette.cmaps(strcmpi({app.cmapPalette.cmaps.name}, 'jturbo')).rgb;
                case 'bone'
                    value = bone(64);
                case 'hot'
                    value = hot(64);
                case 'viridis'
                    value = app.cmapPalette.cmaps(strcmpi({app.cmapPalette.cmaps.name}, 'viridis')).rgb;
                case 'inferno'
                    value = app.cmapPalette.cmaps(strcmpi({app.cmapPalette.cmaps.name}, 'inferno')).rgb;
            end
            colormap(app.mpsAxes, value);
            colormap(app.mraAxes, value);
            colormap(app.nraAxes, value);
            colormap(app.spectrogramAxes, value);
            colormap(app.animatedAxes, value);
        end

%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Update Spectrogram Peak Indicator
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function updatePeakToggle(app)
            if app.TogglePeaksButton.Value
                % Clear whatever peak indicators already exist
                if ~isempty(app.specPeakIndicator)
                    delete(app.specPeakIndicator);
                end
                % Sum current PSTH matrix down the columns
                totalActivity = sum(app.currentPsthMat, 1);
                % Find indices for k max values 
                k = 5;
                [peaks, pk_idx] = findpeaks(totalActivity, 'MinPeakDistance', 20); % find peaks in the pop PSTH
                [peaks, pSortIdx] = sort(peaks, 'descend'); % sort peaks from highest to lowest
                pk_idx = pk_idx(pSortIdx); % sort peak indices according to peak values
                pk_idx = pk_idx(1:min(k, length(pk_idx))); % save the k largest peaks
                % Plot k max indicators on spectrogram axes
                hold(app.spectrogramAxes, 'on');
                app.specPeakIndicator = plot(app.spectrogramAxes, ...
                    pk_idx, ...
                    zeros(1, length(pk_idx)), ...
                    'LineStyle', 'none', ...
                    'Marker', '^', 'MarkerEdgeColor', 'k', ...
                    'MarkerFaceColor', 'w', 'MarkerSize', 10, 'HitTest', 'off');
                hold(app.spectrogramAxes, 'off');
                % Set time step to 1st max and update
            end
        end
        
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Update Spectrogram Population PSTH Overlay
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function updatePPSTHToggle(app)
            if app.TogglePPSTHButton.Value
                % Clear whatever pPSTH line already exists
                if ~isempty(app.specPPSTH)
                    delete(app.specPPSTH);
                end
                % Take average PSTH down the columns, and scale to spectro
                meanActivity = nanmean(app.currentPsthMat, 1) * 1000;
                % Plot pPSTH on spectrogram axes
                hold(app.spectrogramAxes, 'on');
                app.specPPSTH = plot(app.spectrogramAxes, ...
                    1:length(meanActivity), ...
                    meanActivity, ...
                    'Color', 'w', 'LineWidth', 1, 'HitTest', 'off');
                hold(app.spectrogramAxes, 'off');
            end
        end
        
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Release Playback Range Selector
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function releaseRangeSlider(app, ~, event)
            % Get current position of release
            % MAY NEED TO JUST SAVE POS BEFORE
            % NEED MEMORY OF PREVIOUS RANGE, IF IT EXISTS
            app.rangeWinEnd = round(event.Source.CurrentAxes.CurrentPoint(1));
            % Make sure its within the limits of the spectrogram
            if app.rangeWinEnd > app.stepLength
                app.rangeWinEnd = app.stepLength;
            elseif app.rangeWinEnd < 1
                app.rangeWinEnd = 1;
            end
            % Check if the signs are swapped (user slid to the left)
            if app.rangeWinEnd - app.rangeWinStart < 0
                ranges = [app.rangeWinStart app.rangeWinEnd];
                app.rangeWinStart = ranges(2);
                app.rangeWinEnd = ranges(1);
                if ~isempty(app.rangeWinL); app.rangeWinL.XData = [app.rangeWinStart app.rangeWinStart]; end
            end
            % Check if the distance was big enough
            if abs(app.rangeWinEnd - app.rangeWinStart) > 50
                app.rangeWinR = line(app.spectrogramAxes, [app.rangeWinEnd app.rangeWinEnd], [1 100], 'LineWidth', 1, 'Color', [82 255 229]/255, 'HitTest', 'off');
                %app.spectrogramAxes.Children = app.spectrogramAxes.Children([2:end-1 1 end]);
                app.step = app.rangeWinStart;
                app.updatePlotsFromStepChange();
            else % just set a new step value, reset the range limits to full
                % but check first if you're inside the current range...
                %if app.rangeWinEnd > app.rangeWinStart && 
                if ~isempty(app.rangeWinPatch); delete(app.rangeWinPatch); end
                if ~isempty(app.rangeWinL); delete(app.rangeWinL); end
                app.step = app.rangeWinStart;
                app.updatePlotsFromStepChange();
                app.rangeWinStart = 1;
                app.rangeWinEnd = app.stepLength;
            end
            
            app.EphysExplorerUIFigure.WindowButtonUpFcn = '';
            app.EphysExplorerUIFigure.WindowButtonMotionFcn = '';
        end
    
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Draw rectangle while dragging range selector
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~    
        function dragRangeSlider(app, ~, event)
            currentMousePos = round(event.Source.CurrentAxes.CurrentPoint(1));
            if currentMousePos < 1
                app.rangeWinPatch.XData = [app.rangeWinStart 1 1 app.rangeWinStart];
            elseif currentMousePos > app.stepLength
                app.rangeWinPatch.XData = [app.rangeWinStart app.stepLength app.stepLength app.rangeWinStart];
            else
                app.rangeWinPatch.XData = [app.rangeWinStart currentMousePos currentMousePos app.rangeWinStart];
            end
        end

%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Update plots after step change
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function updatePlotsFromStepChange(app)
            app.spectrogramSlider.Value = app.step;
            app.fpsLine.YData = app.stimInfo(app.selection).S(:, app.step);
            app.specLine.XData = [app.step app.step];
            app.rowTypes = {app.unitInfo(app.unitIdxs).cellType};
            switch app.pointType
                case 'color'
                    app.neuronScatter.CData = app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step);
                    app.neuronScatter2.CData = app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step);
                case 'size'
                    app.neuronScatter.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step) * app.maxPointSize;
                    app.neuronScatter2.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step) * app.maxPointSize;
            end
        end
        
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Load region schematic
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~   
        function loadRegionSchematic(app)
            
            % Load region schematic from external file
            schematic = load('Z:\je2527\Analysis\schematic 3D\schematic.mat');
            app.schematicData = schematic.schematic;
            
            %app.regionSchematic = struct(length(app.schematicData));
            
            hold(app.animatedAxes, 'on');
            for i = 1:length(app.schematicData)
                app.regionSchematic{i} = scatter3(app.animatedAxes, ...
                    [app.schematicData(i).x], ...
                    [app.schematicData(i).y], ...
                    [app.schematicData(i).z], ...
                    2, 'k', 'filled');
                app.regionSchematic{i}.Visible = false;
            end
            hold(app.animatedAxes, 'off');
            
            app.schematicSlice = 1;
            
            
%             app.regionSchematic(i) = scatter3(app.animatedAxes, ...
%                     cat(1,app.schematicData.x), ...
%                     cat(1,app.schematicData.y), ...
%                     cat(1,app.schematicData.z), ...
%                     2, 'k', 'filled');
            
        end
        
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Update plots from region/celltype checkbox change
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ 
        function updateFromCheckboxChange(app)
            app.updatePsthMatrix();
            app.updateUnitTable();
            app.update3Dscatter();
            app.updatePeakToggle();
            app.updatePPSTHToggle();
            app.RippleDropDownValueChanged();
        end

%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
%   Evaluate dataset choice at startup
%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~         
        function startDialogCallback(app,~,event)
            switch event.SelectedOption
                case 'Edwards Multispecies'
                    app.DatasetDropdown.Value = 'Edwards Multispecies';
                case 'Yeh Striatum'
                    app.DatasetDropdown.Value = 'Yeh Striatum';
                case 'Moore Males'
                    app.DatasetDropdown.Value = 'Moore Males';
                case 'Moore Females'
                    app.DatasetDropdown.Value = 'Moore Females';
                
            end
            app.DatasetDropdownValueChanged();
        end


        function idxs = getTypeIdxs(app, type)
            
            types = {app.unitInfo.cellType};
            idxs = strcmp(types, type);
            idxs = idxs & app.unitIdxs;
            
        end
        
        function [fs, rs] = getCellTypeMarkers(app)
            
            switch app.FSIconDropDown.Value
                case 'circles'
                    fs = 'o';
                case 'squares'
                    fs = 's';
                case 'triangles'
                    fs = '^';
            end
            
            switch app.RSIconDropDown.Value
                case 'circles'
                    rs = 'o';
                case 'squares'
                    rs = 's';
                case 'triangles'
                    rs = '^';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            uiconfirm(app.EphysExplorerUIFigure, ...
                ['Welcome to Ephys Explorer' newline newline 'Select a dataset'], ...
                'Ephys Explorer', ...
                'Options', {'Edwards Multispecies', 'Yeh Striatum', 'Moore Males', 'Moore Females'}, ...
                'CloseFcn', @app.startDialogCallback);
            
        end

        % Value changed function: stimulusDropdown
        function stimulusDropdownValueChanged(app, event)
            app.updateStimSelection();
            app.updatePsthMatrix();
            app.updatePeakToggle();
            app.updatePPSTHToggle();
            if ~isempty(app.rangeWinPatch); delete(app.rangeWinPatch); end
            if ~isempty(app.rangeWinL); delete(app.rangeWinL); end
            if ~isempty(app.rangeWinR); delete(app.rangeWinR); end
            app.rangeWinStart = 1;
            app.rangeWinEnd = app.stepLength;
            app.rowTypes = {app.unitInfo(app.unitIdxs).cellType};
            switch app.pointType
                case 'color'
                    app.neuronScatter.CData = app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step);
                    app.neuronScatter2.CData = app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step);
                case 'size'
                    app.neuronScatter.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step) * app.maxPointSize;
                    app.neuronScatter2.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step) * app.maxPointSize;
            end
        end

        % Value changed function: colormapDropDown
        function colormapDropDownValueChanged(app, event)
            app.updateColorMap();
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)

            value = app.PlayButton.Value;
            
            % Gather components to enable and disable
            comps(1)    = app.subjectListBox;
            comps(2)    = app.stimulusDropdown;
            comps(3)    = app.spectrogramSlider;
            comps(4)    = app.playbackMultiplierField;
            comps(5)    = app.colormapDropDown;
            comps(6)    = app.PointTypeDropDown;
            comps(7)    = app.MaxPointSizeEditField;
            comps(8)    = app.ModeDropDown;
            comps(9)    = app.ResetButton;
            comps(10)   = app.RegSpikersCheckBox;
            comps(11)   = app.FastSpikersCheckBox;
            comps(12)   = app.RSIconDropDown;
            comps(13)   = app.FSIconDropDown;
            comps(14)   = app.ShowRegionsButton;
            comps(15)   = app.regionFilterButton;
            comps(16)   = app.regionClearButton;
            comps(17)   = app.regionAllButton;
            comps(18)   = app.box_L2a;
            comps(19)   = app.box_L2b;
            comps(20)   = app.box_L;
            comps(21)   = app.box_L3;
            comps(22)   = app.box_L1;
            comps(23)   = app.box_CSt;
            comps(24)   = app.box_NCM;
            comps(25)   = app.box_NCL;
            comps(26)   = app.box_CMM;
            comps(27)   = app.box_CLM;
            comps(28)   = app.box_HVC;
            comps(29)   = app.PlotTuningButton;
            comps(30)   = app.TuningMetricsDropDown;
            comps(31)   = app.WriteFramesButton;
            comps(32)   = app.StitchVideoButton;
            comps(33)   = app.FPSEditField;
            comps(34)   = app.TogglePPSTHButton;
            comps(35)   = app.TogglePeaksButton;
            
            if value
                app.PlayButton.Text = 'Stop';
                set(comps, 'Enable', 'off');
                start(app.Timer);
            else
                stop(app.Timer);
                set(comps, 'Enable', 'on');
                app.PlayButton.Text = 'Play';
            end
            
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.step = app.rangeWinStart;
            app.fpsLine.YData = app.stimInfo(app.selection).FPS ./ max(app.stimInfo(app.selection).FPS, [], 'all');
            app.spectrogramSlider.Value = app.step;
            app.specLine.XData = [app.step app.step];
            
            app.rowTypes = {app.unitInfo(app.unitIdxs).cellType};
            
            switch app.pointType
                case 'color'
                    app.neuronScatter.CData = app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step);
                    app.neuronScatter2.CData = app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step);
                case 'size'
                    app.neuronScatter.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step) * app.maxPointSize;
                    app.neuronScatter2.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step) * app.maxPointSize;
            end
            
        end

        % Value changing function: spectrogramSlider
        function spectrogramSliderValueChanging(app, event)
            changingValue = round(event.Value);
            if changingValue < 0 % doesn't seem to be working, need to limit slider somehow
                changingValue = 0;
            end
            
            app.rowTypes = {app.unitInfo(app.unitIdxs).cellType};
            
            app.step = changingValue;
            app.fpsLine.YData = app.stimInfo(app.selection).S(:, app.step);
            app.specLine.XData = [app.step app.step];
            switch app.pointType
                case 'color'
                    app.neuronScatter.CData = app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step);
                    app.neuronScatter2.CData = app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step);
                case 'size'
                    app.neuronScatter.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step) * app.maxPointSize;
                    app.neuronScatter2.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step) * app.maxPointSize;
            end
                
            
        end

        % Value changed function: spectrogramSlider
        function spectrogramSliderValueChanged(app, event)
            app.step = round(app.spectrogramSlider.Value);
            
            app.fpsLine.YData = app.stimInfo(app.selection).S(:, app.step);
            app.specLine.XData = [app.step app.step];
            
            app.rowTypes = {app.unitInfo(app.unitIdxs).cellType};
            switch app.pointType
                case 'color'
                    app.neuronScatter.CData = app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step);
                    app.neuronScatter2.CData = app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step);
                case 'size'
                    app.neuronScatter.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step) * app.maxPointSize;
                    app.neuronScatter2.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step) * app.maxPointSize;
            end
            
        end

        % Value changed function: subjectListBox
        function subjectListBoxValueChanged(app, event)
            app.updatePsthMatrix();
            app.updateUnitTable();
            app.update3Dscatter();
            app.updatePeakToggle();
            app.updatePPSTHToggle();
            app.RippleDropDownValueChanged();
        end

        % Cell selection callback: UnitTable
        function UnitTableCellSelection(app, event)
            indices = event.Indices;
            if size(indices, 1) > 1
                indices = indices(1,:);
            end
            unitIdx = app.UnitTable.RowName(indices(1,1));
            unit = round(str2double(unitIdx{1}));
            
            % Update MRA plot
            if contains(app.DatasetDropdown.Value, 'Moore')
                varname = 'drFR';
                tmfname = 'tmf';
                smfname = 'smf';
            elseif contains(app.DatasetDropdown.Value, 'Yeh')
                varname = 'rsFR';
                tmfname = 'tmf';
                smfname = 'smf';
            else
                varname = 'RS';
                tmfname = 'TMF';
                smfname = 'SMF';
            end
            
            if strcmp(app.RippleDropDown.Value, 'MRA')
                if ~all(isnan(app.MRA(unit).(varname)))
                    imagesc(app.mraAxes, app.MRA(unit).(tmfname), app.MRA(unit).(smfname), app.MRA(unit).(varname));
                        app.mraAxes.XTick = [min(app.MRA(unit).(tmfname)) 0 max(app.MRA(unit).(tmfname))];
                        %app.mraAxes.YTick = [min(app.MRA(unit).(smfname)) max(app.MRA(unit).(smfname))];
                        app.mraAxes.YTick = app.MRA(unit).(smfname);
                        app.mraAxes.XTickLabel = [min(app.MRA(unit).(tmfname)) 0 max(app.MRA(unit).(tmfname))];
                        %app.mraAxes.YTickLabel = [min(app.MRA(unit).(smfname)) max(app.MRA(unit).(smfname))];
                        app.mraAxes.YTickLabel = app.MRA(unit).(smfname);
                        app.mraAxes.XLim = [min(app.MRA(unit).(tmfname)) max(app.MRA(unit).(tmfname))];
                        app.mraAxes.YLim = [min(app.MRA(unit).(smfname)) max(app.MRA(unit).(smfname))];
                        switch app.ModeDropDown.Value
                            case 'light'
                                app.mraAxes.XColor = 'k';
                                app.mraAxes.YColor = 'k';
                            case 'dark'
                                app.mraAxes.XColor = [0.65 0.65 0.65];
                                app.mraAxes.YColor = [0.65 0.65 0.65];
                        end
                        app.mraAxes.XLabel.String = 'Hz';
                        app.mraAxes.YLabel.String = 'cyc/kHz';
                        app.mraAxes.FontSize = 10;
                        app.mraAxes.Layer = 'top';
                        app.mraAxes.YDir = 'normal';
                     app.RippleResponseLabel.Visible = false;
                else
                    cla(app.mraAxes);
                        app.mraAxes.XColor = 'none';
                        app.mraAxes.YColor = 'none';
                    app.RippleResponseLabel.Visible = true;
                    switch app.ModeDropDown.Value
                        case 'light'
                            app.RippleResponseLabel.FontColor = 'k';
                        case 'dark'
                            app.RippleResponseLabel.FontColor = [0.65 0.65 0.65];
                    end
                end
            end
            
            % Update NRA/FRA plot
            if strcmp(app.NoiseDropDown.Value, 'NRA') % Noise response for Jacob's data
                nra_axes_y = [20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 150 200]; % didn't store these nicely, so just hard-code
                nra_axes_x = [10 15 20 25 30 35 40 45 60 70 80 90 100 125 150 175 200 250 300];
                imagesc(app.nraAxes, nra_axes_x, nra_axes_y, flipud(app.NRA(unit).crossCorr_up));
                    app.nraAxes.XTick = [min(nra_axes_x) max(nra_axes_x)];
                    app.nraAxes.YTick = [min(nra_axes_y) max(nra_axes_y)];
                    app.nraAxes.XTickLabel = [min(nra_axes_x) max(nra_axes_x)];
                    app.nraAxes.YTickLabel = [min(nra_axes_y) max(nra_axes_y)];
                    app.nraAxes.XLim = [min(nra_axes_x) max(nra_axes_x)];
                    app.nraAxes.YLim = [min(nra_axes_y) max(nra_axes_y)];
                    switch app.ModeDropDown.Value
                            case 'light'
                                app.nraAxes.XColor = 'k';
                                app.nraAxes.YColor = 'k';
                            case 'dark'
                                app.nraAxes.XColor = [0.65 0.65 0.65];
                                app.nraAxes.YColor = [0.65 0.65 0.65];
                    end
                    app.nraAxes.XLabel.String = 'ISI (ms)';
                    app.nraAxes.YLabel.String = 'Duration (ms)';
                    app.nraAxes.FontSize = 10;
                    app.nraAxes.Layer = 'top';
                    app.nraAxes.YDir = 'normal';
                    app.nraAxes.CLim = [0 1];
                app.NoiseResponseLabel.Visible = false;
            elseif strcmp(app.NoiseDropDown.Value, 'FRA') % Tone response for Jordan's data
                if ~all(isnan(app.FRA(unit).fr_stm_RS))
                    imagesc(app.nraAxes, app.FRA(unit).FRQ, app.FRA(unit).LVL, app.FRA(unit).fr_stm_RS);
                        app.nraAxes.YDir = 'normal';
                        app.nraAxes.XTick = [min(app.FRA(unit).FRQ) max(app.FRA(unit).FRQ)];
                        app.nraAxes.YTick = [min(app.FRA(unit).LVL) max(app.FRA(unit).LVL)];
                        app.nraAxes.XTickLabel = [min(app.FRA(unit).FRQ) max(app.FRA(unit).FRQ)];
                        app.nraAxes.YTickLabel = [min(app.FRA(unit).LVL) max(app.FRA(unit).LVL)];
                        app.nraAxes.XLim = [min(app.FRA(unit).FRQ) max(app.FRA(unit).FRQ)];
                        app.nraAxes.YLim = [min(app.FRA(unit).LVL) max(app.FRA(unit).LVL)];
                        switch app.ModeDropDown.Value
                                case 'light'
                                    app.nraAxes.XColor = 'k';
                                    app.nraAxes.YColor = 'k';
                                case 'dark'
                                    app.nraAxes.XColor = [0.65 0.65 0.65];
                                    app.nraAxes.YColor = [0.65 0.65 0.65];
                        end
                        app.nraAxes.XLabel.String = 'kHz';
                        app.nraAxes.YLabel.String = 'dB';
                        app.nraAxes.FontSize = 10;
                        app.nraAxes.Layer = 'top';
                        app.nraAxes.YDir = 'normal';
                    app.NoiseResponseLabel.Visible = false;
                else
                    app.NoiseResponseLabel.Visible = true;
                    cla(app.nraAxes);
                        app.nraAxes.XColor = 'none';
                        app.nraAxes.YColor = 'none';
                    switch app.ModeDropDown.Value
                        case 'light'
                            app.NoiseResponseLabel.FontColor = 'k';
                        case 'dark'
                            app.NoiseResponseLabel.FontColor = [0.65 0.65 0.65];
                    end
                end
            end
            
            % Update AP plot
            cla(app.apAxes)
            if strcmp(app.unitInfo(unit).cellType, 'FS')
                ap_color = app.cellTypePalette{1};
            else
                ap_color = app.cellTypePalette{2};
            end
            hold(app.apAxes, 'on');
                patch(app.apAxes, [1:length(app.unitInfo(unit).AP_avg) fliplr(1:length(app.unitInfo(unit).AP_avg))], ...
                        [app.unitInfo(unit).AP_avg + app.unitInfo(unit).AP_std fliplr(app.unitInfo(unit).AP_avg - app.unitInfo(unit).AP_std)], ...
                        [0.5 0.5 0.5], 'LineStyle', 'none', 'FaceAlpha', 0.2);
                plot(app.apAxes, app.unitInfo(unit).AP_avg + app.unitInfo(unit).AP_std, '--', 'Color', ap_color, 'LineWidth', 0.1);
                plot(app.apAxes, app.unitInfo(unit).AP_avg - app.unitInfo(unit).AP_std, '--', 'Color', ap_color, 'LineWidth', 0.1);
                plot(app.apAxes, app.unitInfo(unit).AP_avg, '-', 'Color', ap_color, 'LineWidth', 2);
            hold(app.apAxes, 'off');
                app.apAxes.YLim = [min([app.unitInfo.AP_avg] + [app.unitInfo.AP_std], [], 'all') + 0.25 * min([app.unitInfo.AP_std], [], 'all'), ...
                    max([app.unitInfo.AP_avg] + [app.unitInfo.AP_std], [], 'all') + 0.25 * max([app.unitInfo.AP_std], [], 'all')];
        
            % Highlight unit on PSTH plot
            hold(app.animatedAxes, 'on');
                delete(app.currentMarkedCell);
                app.currentMarkedCell = scatter3(app.animatedAxes, app.unitInfo(unit).X + app.jitter(unit,1), app.unitInfo(unit).Y, app.unitInfo(unit).Z, 180, [1 0 0]);
            hold(app.animatedAxes, 'off');
                
            
        end

        % Value changed function: PointTypeDropDown
        function PointTypeDropDownValueChanged(app, event)
            app.pointType = app.PointTypeDropDown.Value;
            app.rowTypes = {app.unitInfo(app.unitIdxs).cellType};
            switch app.pointType
                case 'color'
                    app.neuronScatter.CData = app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step);
                    app.neuronScatter.SizeData = 1 + app.maxPointSize;
                    app.neuronScatter2.CData = app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step);
                    app.neuronScatter2.SizeData = 1 + app.maxPointSize;
                case 'size'
                    app.checkSizeColorMode();
                    app.neuronScatter.CData = app.sizeColorVector;
                    app.neuronScatter.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'FS') ,app.step) * app.maxPointSize;
                    app.neuronScatter2.CData = app.sizeColorVector;
                    app.neuronScatter2.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'RS') ,app.step) * app.maxPointSize;
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            app.checkSizeColorMode();
            
            % Gather components - PANELS
            panels(1) = app.subjectsPanel;
            panels(2) = app.cellTypeSelectorPanel;
            panels(3) = app.StimulusPanel;
            panels(4) = app.regionSelectorPanel;
            panels(5) = app.cellTypeSelectorPanel;
            panels(6) = app.DisplayOptionsPanel;
            panels(7) = app.AnimatePSTHPanel;
            panels(8) = app.rippleTuningPanel;
            panels(9) = app.DisplayTuningMetricsPanel;
            panels(10) = app.ExportVideoPanel;
            panels(11) = app.LoadDatasetPanel;
            
            % Gather components - BUTTONS
            buttons(1) = app.regionFilterButton;
            buttons(2) = app.regionClearButton;
            buttons(3) = app.regionAllButton;
            buttons(4) = app.PlayButton;
            buttons(5) = app.ResetButton;
            buttons(6) = app.PlotTuningButton;
            buttons(7) = app.WriteFramesButton;
            buttons(8) = app.StitchVideoButton;
            buttons(9) = app.ShowRegionsButton;
            buttons(10) = app.TogglePPSTHButton;
            buttons(11) = app.TogglePeaksButton;
            
            % Gather components - LISTBOXES
            lboxes(1) = app.subjectListBox;
            
            % Gather components - TABLES
            tables(1) = app.UnitTable;
            
            % Gather components - EDIT FIELDS
            efields(1) = app.MaxPointSizeEditField;
            efields(2) = app.playbackMultiplierField;
            efields(3) = app.FPSEditField;
            
            % Gather components - DROPDOWNS
            ddowns(1) = app.stimulusDropdown;
            ddowns(2) = app.colormapDropDown;
            ddowns(3) = app.PointTypeDropDown;
            ddowns(4) = app.ModeDropDown;
            ddowns(5) = app.TuningMetricsDropDown;
            ddowns(6) = app.FSIconDropDown;
            ddowns(7) = app.RSIconDropDown;
            ddowns(8) = app.DatasetDropdown;
            ddowns(9) = app.UnitDropDown;
            ddowns(10) = app.SongDropDown;
            ddowns(11) = app.RippleDropDown;
            ddowns(12) = app.NoiseDropDown;
            
            % Gather components - FIELD LABELS
            flabels(1) = app.PlaybackMultiplierLabel;
            flabels(2) = app.ColorMapLabel;
            flabels(3) = app.PointTypeDropDownLabel;
            flabels(4) = app.MaxPointSizeEditFieldLabel;
            flabels(5) = app.ModeDropDownLabel;
            flabels(6) = app.FPSEditFieldLabel;
            flabels(7) = app.RegSpikersCheckBox;
            flabels(8) = app.FastSpikersCheckBox;
            flabels(9) = app.box_L2a;
            flabels(10) = app.box_L2b;
            flabels(11) = app.box_L;
            flabels(12) = app.box_L3;
            flabels(13) = app.box_L1;
            flabels(14) = app.box_CSt;
            flabels(15) = app.box_NCM;
            flabels(16) = app.box_NCL;
            flabels(17) = app.box_CMM;
            flabels(18) = app.box_CLM;
            flabels(19) = app.box_HVC;
            flabels(20) = app.RippleResponseLabel;
            flabels(21) = app.NoiseResponseLabel;
            
            switch app.ModeDropDown.Value
                case 'light'
                    set(app.animatedAxes, ...
                        'Color', [1 1 1], ...
                        'GridColor', [0.15 0.15 0.15], ...
                        'XColor', [0.15 0.15 0.15], ...
                        'YColor', [0.15 0.15 0.15], ...
                        'ZColor', [0.15 0.15 0.15]);
                    set(buttons, ...
                        'BackgroundColor', [0.96 0.96 0.96], ...
                        'FontColor', [0 0 0]);
                    set(lboxes, ...
                        'BackgroundColor', [1 1 1], ...
                        'FontColor', [0 0 0]);
                    set(tables, ...
                        'BackgroundColor', [1 1 1], ...
                        'ForegroundColor', [0 0 0]);
                    set(efields, ...
                        'BackgroundColor', [1 1 1], ...
                        'FontColor', [0 0 0]);
                    set(ddowns, ...
                        'BackgroundColor', [0.96 0.96 0.96], ...
                        'FontColor', [0 0 0]);
                    set(panels, ...
                        'BackgroundColor', [0.94 0.94 0.94], ...
                        'ForegroundColor', [0 0 0]);
                    set(flabels, 'FontColor', [0 0 0]);
                    app.EphysExplorerUIFigure.Color = [0.94 0.94 0.94];
                    app.fpsLine.Color = 'k';
                case 'dark'
                    set(app.animatedAxes, ...
                        'Color', [0 0 0], ...
                        'GridColor', [0.85 0.85 0.85], ...
                        'XColor', [0.65 0.65 0.65], ...
                        'YColor', [0.65 0.65 0.65], ...
                        'ZColor', [0.65 0.65 0.65]);
                    set(buttons, ...
                        'BackgroundColor', [0.25 0.25 0.25], ...
                        'FontColor', [0.75 0.75 0.75]);
                    set(lboxes, ...
                        'BackgroundColor', [0.15 0.15 0.15], ...
                        'FontColor', [0.65 0.65 0.65]);
                    set(tables, ...
                        'BackgroundColor', [0 0 0], ...
                        'ForegroundColor', [1 1 1]);
                    set(efields, ...
                        'BackgroundColor', [0.15 0.15 0.15], ...
                        'FontColor', [0.65 0.65 0.65]);
                    set(ddowns, ...
                        'BackgroundColor', [0.15 0.15 0.15], ...
                        'FontColor', [0.65 0.65 0.65]);
                    set(panels, ...
                        'BackgroundColor', [0.06 0.06 0.06], ...
                        'ForegroundColor', [0.65 0.65 0.65]);
                    set(flabels, 'FontColor', [0.65 0.65 0.65]);
                    app.EphysExplorerUIFigure.Color = [0.06 0.06 0.06];
                    app.fpsLine.Color = 'w';
            end
            
            switch app.pointType
                case 'size'
                    app.neuronScatter.CData = app.sizeColorVector;
                    app.neuronScatter2.CData = app.sizeColorVector;
            end
            
        end

        % Value changed function: MaxPointSizeEditField
        function MaxPointSizeEditFieldValueChanged(app, event)
            app.maxPointSize = app.MaxPointSizeEditField.Value;
            app.rowTypes = {app.unitInfo(app.unitIdxs).cellType};
            switch app.pointType
                case 'color'
                    app.neuronScatter.SizeData = 1 + app.maxPointSize;
                    app.neuronScatter2.SizeData = 1 + app.maxPointSize;
                case 'size'
                    app.neuronScatter.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'FS'),app.step) * app.maxPointSize;
                    app.neuronScatter2.SizeData = 1 + app.currentPsthMat(strcmp(app.rowTypes,'RS'),app.step) * app.maxPointSize;
            end
        end

        % Button pushed function: PlotTuningButton
        function PlotTuningButtonPushed(app, event)
            switch app.TuningMetricsDropDown.Value
                    % Replace PSTH with tuning metrics
                    % Either reset with 'Play', or disable until cleared
            end
        end

        % Value changed function: TogglePeaksButton
        function TogglePeaksButtonValueChanged(app, event)
            value = app.TogglePeaksButton.Value;
            
            if value
                app.updatePeakToggle();
            else
                if ~isempty(app.specPeakIndicator)
                    delete(app.specPeakIndicator);
                end
            end
            
        end

        % Value changed function: TogglePPSTHButton
        function TogglePPSTHButtonValueChanged(app, event)
            value = app.TogglePPSTHButton.Value;
            
            if value
                app.updatePPSTHToggle();
            else
                if ~isempty(app.specPPSTH)
                    delete(app.specPPSTH);
                end
            end
        end

        % Button pushed function: WriteFramesButton
        function WriteFramesButtonPushed(app, event)
            
            % Check if user wants to adjust playback multiplier
            update_pbm = questdlg('Do you want to adjust the playback multiplier?', ...
                'Check playback rate', ...
                'Set to 1', 'Set manually', 'No', 'Set to 1');
            switch update_pbm
                case 'Set to 1'
                    pbm = 1;
                    app.playbackMultiplierField.Value = 1;
                case 'Set manually'
                    return
                case 'No'
                    pbm = app.playbackMultiplierField.Value;
            end
            
            % Open file selector UI
            fpath = uigetdir('Z:\je2527\','Select destination for frame data');
            
            if isequal(fpath,0)
                return
            else
                
                % Determine how many frames to write based on selection
                
                frames = app.rangeWinEnd - 1 - app.step;
                frames = floor(frames / pbm);
            
                % Get position data of 3D plot and whole GUI to crop
                pos = app.spectrogramAxes.Position;
                figPos = app.EphysExplorerUIFigure.Position;
                
                margin = 5; % in pixels, a small margin to include on sides of plot
                
                % Capture the area with spectrogram and 3D axis
                rect = [pos(1)-margin pos(2) pos(3)+2*margin figPos(4)-pos(2)];
                
                % Display a loading bar
                loadingBar = uiprogressdlg(app.EphysExplorerUIFigure, 'Title', 'Writing movie frames', ...
                    'Message', 'Initiating');
                drawnow;
                
                % Loop through and capture frames
                % NOTE GETFRAME IS SLOW - ALTERNATIVELY, WRITE FIG AS IMG
                % note: tried this, exportapp as image equally slow
                % https://www.mathworks.com/matlabcentral/answers/613051-getframe-very-slow-in-loop
                
                % Create a directory to store images
                mkdir(fpath, 'frames')
                
                for i = 1:frames
                    loadingBar.Message = ['Writing frame ' num2str(i) '/' num2str(frames)];
                    loadingBar.Value = i / frames;
                    
                    % Capture the current frame
                    mov = getframe(app.EphysExplorerUIFigure, rect);
                    save([fpath '\frames\frame' sprintf('%04d', i) '.mat'], 'mov', '-mat');
                    %exportapp(app.EphysExplorerUIFigure, [fpath 'imgs\frame' num2str(i) '.jpg']);
                    
                    animatePlots(app);
                    
                end
                
                % Write frames to file
                loadingBar.Message = 'Writing movie to file';
                
    
                close(loadingBar);
            end
            
        end

        % Callback function
        function UIFigureWindowButtonDown(app, event)
            
        end

        % Callback function
        function ReleaseRangeSlider(app, event)
            
        end

        % Button down function: spectrogramAxes
        function spectrogramAxesButtonDown(app, event)
            % CUSTOM BUTTON DETECTION FOR DRAGGING RANGE WINDOW IN SPEC
            if ~app.PlayButton.Value % make sure no animation happening
                if ~strcmp(event.Source.Parent.SelectionType, 'alt')
                    % store the current mouse position
                    app.rangeWinStart = round(event.Source.CurrentPoint(1));
                    % delete any range indicators that already exist
                    if ~isempty(app.rangeWinL); delete(app.rangeWinL); end
                    if ~isempty(app.rangeWinR); delete(app.rangeWinR); end
                    if ~isempty(app.rangeWinPatch); delete(app.rangeWinPatch); end
                    % draw the first range indicator
                    app.rangeWinL = line(app.spectrogramAxes, [app.rangeWinStart app.rangeWinStart], [1 100], 'LineWidth', 1, 'Color', [82 255 229]/255);
                    % THIS IS CLOSE for ensuring range indicators are behind
                    %app.spectrogramAxes.Children = app.spectrogramAxes.Children([2:end-1 1 end]);
                    % create the rectangle/patch to indicate the selection
                    app.rangeWinPatch = patch(app.spectrogramAxes, ...
                        'XData', [app.rangeWinStart app.rangeWinStart app.rangeWinStart app.rangeWinStart], ...
                        'YData', [0 0 100 100], ...
                        'FaceColor', [82 255 229]/255, ...
                        'EdgeColor', 'none', ...
                        'FaceAlpha', 0.25, ...
                        'HitTest', 'off');
                    %app.spectrogramAxes.Children = app.spectrogramAxes.Children([2:end-1 1 end]);
                    % attach the release and motion callbacks to the mouse down
                    app.EphysExplorerUIFigure.WindowButtonMotionFcn = @app.dragRangeSlider;
                    app.EphysExplorerUIFigure.WindowButtonUpFcn = @app.releaseRangeSlider;
                else % clear existing range limits if right-click
                    if ~isempty(app.rangeWinPatch); delete(app.rangeWinPatch); end
                    if ~isempty(app.rangeWinL); delete(app.rangeWinL); end
                    if ~isempty(app.rangeWinR); delete(app.rangeWinR); end
                    app.rangeWinStart = 1;
                    app.rangeWinEnd = app.stepLength;
                end

            end
        end

        % Value changed function: ShowRegionsButton
        function ShowRegionsButtonPushed(app, event)
            % Toggle 3D brain model
            value = app.ShowRegionsButton.Value;
            
            if value
                app.ShowRegionsButton.Text = ['Hide' newline 'Regions'];
                app.FocusButton.Enable = true;
                for i = 1:length(app.regionSchematic)
                    app.regionSchematic{i}.Visible = true;
                end
            else
                for i = 1:length(app.regionSchematic)
                    app.regionSchematic{i}.Visible = false;
                end
                app.FocusButton.Enable = false;
                app.FocusCoordsLabel.Enable = false;
                app.FocusMinusButton.Enable = false;
                app.FocusPlusButton.Enable = false;
                app.ShowRegionsButton.Text = ['Show' newline 'Regions'];
            end
            
        end

        % Button pushed function: StitchVideoButton
        function StitchVideoButtonPushed(app, event)
            % Open file selector UI
            fpath = uigetdir('Z:\je2527\','Select directory containing frame data');
            
            loadingBar = uiprogressdlg(app.EphysExplorerUIFigure, 'Title', 'Stitching movie frames', ...
                    'Message', 'Initiating');
            drawnow;
            
            files = dir(fpath);
            files = files(startsWith({files.name},'frame'));
            
            % Initiate video writer
            FPS = app.FPSEditField.Value;
                %mov(frames) = struct('cdata',[],'colormap',[]);
            writer = VideoWriter([fpath '\_video.avi'], 'Motion JPEG AVI');
            writer.FrameRate = FPS;
            
            open(writer);
            
                %
            for i = 1:length(files)
                
                loadingBar.Message = ['Stitching frame ' num2str(i) '/' num2str(length(files))];
                loadingBar.Value = i / length(files);
                
                frame = load([files(i).folder '\' files(i).name]);
                writeVideo(writer, frame.mov);
                
            end
            
            close(writer);
            close(loadingBar);
            
        end

        % Value changed function: FastSpikersCheckBox
        function FastSpikersCheckBoxValueChanged(app, event)
            %value = app.FastSpikersCheckBox.Value;
            app.updateFromCheckboxChange();
            
        end

        % Value changed function: RegSpikersCheckBox
        function RegSpikersCheckBoxValueChanged(app, event)
            %value = app.RegSpikersCheckBox.Value;
            app.updateFromCheckboxChange();
        end

        % Button pushed function: regionFilterButton
        function regionFilterButtonPushed(app, event)
            app.updateFromCheckboxChange();
        end

        % Button pushed function: regionClearButton
        function regionClearButtonPushed(app, event)
            app.box_L2a.Value = 0;
            app.box_L2b.Value = 0;
            app.box_L.Value = 0;
            app.box_L3.Value = 0;
            app.box_L1.Value = 0;
            app.box_CSt.Value = 0;
            app.box_NCM.Value = 0;
            app.box_NCL.Value = 0;
            app.box_CMM.Value = 0;
            app.box_CLM.Value = 0;
            app.box_HVC.Value = 0;
            app.updateFromCheckboxChange();
        end

        % Button pushed function: regionAllButton
        function regionAllButtonPushed(app, event)
            app.box_L2a.Value = 1;
            app.box_L2b.Value = 1;
            app.box_L.Value = 1;
            app.box_L3.Value = 1;
            app.box_L1.Value = 1;
            app.box_CSt.Value = 1;
            app.box_NCM.Value = 1;
            app.box_NCL.Value = 1;
            app.box_CMM.Value = 1;
            app.box_CLM.Value = 1;
            app.box_HVC.Value = 1;
            app.updateFromCheckboxChange();
        end

        % Value changed function: FocusButton
        function FocusButtonValueChanged(app, event)
            value = app.FocusButton.Value;
            
            if value
                app.FocusCoordsLabel.Enable = true;
                app.FocusMinusButton.Enable = true;
                app.FocusPlusButton.Enable = true;
                
                for i = 1:length(app.regionSchematic)
                    if i == app.schematicSlice
                        app.regionSchematic{i}.Visible = true;
                    else
                        app.regionSchematic{i}.Visible = false;
                    end
                end
                
            else
                app.FocusCoordsLabel.Enable = false;
                app.FocusMinusButton.Enable = false;
                app.FocusPlusButton.Enable = false;
                
                for i = 1:length(app.regionSchematic)
                    app.regionSchematic{i}.Visible = true;
                end
                
            end
        end

        % Button pushed function: FocusPlusButton
        function FocusPlusButtonPushed(app, event)
            
            if app.schematicSlice == length(app.regionSchematic)
                app.schematicSlice = 1;
            else
                app.schematicSlice = app.schematicSlice + 1;
            end
            
            for i = 1:length(app.regionSchematic)
                if i == app.schematicSlice
                    app.regionSchematic{i}.Visible = true;
                else
                    app.regionSchematic{i}.Visible = false;
                end
            end
            
        end

        % Button pushed function: FocusMinusButton
        function FocusMinusButtonPushed(app, event)
            
            if app.schematicSlice == 1
                app.schematicSlice = length(app.regionSchematic);
            else
                app.schematicSlice = app.schematicSlice - 1;
            end
            
            for i = 1:length(app.regionSchematic)
                if i == app.schematicSlice
                    app.regionSchematic{i}.Visible = true;
                else
                    app.regionSchematic{i}.Visible = false;
                end
            end
            
        end

        % Value changed function: DatasetDropdown
        function DatasetDropdownValueChanged(app, event)
            value = app.DatasetDropdown.Value;
            
            switch value
                case 'Edwards Multispecies'
                    
                    dataPath = 'Z:\je2527\Ephys\1. Multispecies\';
                    loadingBar = uiprogressdlg(app.EphysExplorerUIFigure, 'Title', 'Loading data', ...
                        'Message', 'Loading unitInfo...', 'Indeterminate', 'on');
                    drawnow;
                    
                    % Load unitInfo
                    app.unitInfo = load([dataPath 'unitInfo.mat']);
                    app.unitInfo = app.unitInfo.unitInfo;
                    
                    % Clean up coordinates
                    coords = zeros(length(app.unitInfo),3);
                    for row = 1:length(app.unitInfo)
                        coords(row,:) = app.unitInfo(row).xyz;
                    end
                    xcoords = num2cell(coords(:,1));
                    ycoords = num2cell(coords(:,2));
                    zcoords = num2cell(coords(:,3)*(-1));
                    [app.unitInfo(:).X] = deal(xcoords{:});
                    [app.unitInfo(:).Y] = deal(ycoords{:});
                    [app.unitInfo(:).Z] = deal(zcoords{:});
                    
                    % Add jitter to points
                    app.jitter = app.jitterRange(1) + (app.jitterRange(2)-app.jitterRange(1)).*rand(length([app.unitInfo.X]),1);
                    
                    % Load stimInfo
                    loadingBar.Message = 'Loading stimInfo...';
                    app.stimInfo = load([dataPath 'songInfo.mat']);
                    app.stimInfo = app.stimInfo.stimInfo;
                        % Clean up stim names
                        for i = 1:length({app.stimInfo.name})
                            seps = strsplit(app.stimInfo(i).name, '_');
                            app.stimInfo(i).name_formatted = [seps{2} ' ' seps{3}];
                        end
                    % Load songPSTH
                    loadingBar.Message = 'Loading songPSTH...'; drawnow;
                    app.songPSTH = load([dataPath 'songPSTH.mat']);
                    app.songPSTH = app.songPSTH.songPSTH;
                    % Load MRA
                    loadingBar.Message = 'Loading MRA...'; drawnow;
                    app.MRA = load([dataPath 'MRA.mat']);
                    app.MRA = app.MRA.MRA;
                    % Load NRA
                    loadingBar.Message = 'Loading NRA...'; drawnow;
                    app.NRA = load([dataPath 'NRA.mat']);
                    app.NRA = app.NRA.NRA;
                    
                    % Update tuning options for NRA
                    app.NoiseDropDown.Items = {'NRA', 'Population NRA'};
                    app.NoiseDropDown.Value = 'NRA';
                    
                    
                case 'Yeh Striatum'
                    
                    dataPath = 'Z:\yy2722\Tim\Ephys\Recordings\Mat data\';
                    loadingBar = uiprogressdlg(app.EphysExplorerUIFigure, 'Title', 'Loading data', ...
                        'Message', 'Loading unitInfo...', 'Indeterminate', 'on');
                    drawnow;
                    
                    % Load unitInfo
                    app.unitInfo = load([dataPath 'unitInfo.mat']);
                    app.unitInfo = app.unitInfo.unitInfo;
                    
                    % Clean up coordinates
                    coords = zeros(length(app.unitInfo),3);
                    for row = 1:length(app.unitInfo)
                        coords(row,:) = app.unitInfo(row).xyz;
                    end
                    xcoords = num2cell(coords(:,1));
                    ycoords = num2cell(coords(:,2));
                    zcoords = num2cell(coords(:,3)*(-1));
                    [app.unitInfo(:).X] = deal(xcoords{:});
                    [app.unitInfo(:).Y] = deal(ycoords{:});
                    [app.unitInfo(:).Z] = deal(zcoords{:});
                    
                    % Add jitter to points
                    app.jitter = app.jitterRange(1) + (app.jitterRange(2)-app.jitterRange(1)).*rand(length([app.unitInfo.X]),1);
                    
                    % Load stimInfo
                    loadingBar.Message = 'Loading stimInfo...';
                    app.stimInfo = load([dataPath 'StimInfo\songInfo.mat']);
                    app.stimInfo = app.stimInfo.songInfo;
                        % Clean up stim names
                        for i = 1:length({app.stimInfo.name})
                            seps = strsplit(app.stimInfo(i).name, '_');
                            app.stimInfo(i).name_formatted = [seps{2} ' ' seps{3}];
                        end
                    % Load songPSTH
                    loadingBar.Message = 'Loading songPSTH...'; drawnow;
                    app.songPSTH = load([dataPath 'songPSTH.mat']);
                    app.songPSTH = app.songPSTH.songPSTH;
                    % Load MRA
                    loadingBar.Message = 'Loading MRA...'; drawnow;
                    app.MRA = load([dataPath 'MRA.mat']);
                    app.MRA = app.MRA.MRA;
                    % Load FRA
                    loadingBar.Message = 'Loading FRA...'; drawnow;
                    app.FRA = load([dataPath 'FRA.mat']);
                    app.FRA = app.FRA.FRA;
                    
                    % Update tuning options for FRA
                    app.NoiseDropDown.Items = {'FRA', 'Population FRA'};
                    app.NoiseDropDown.Value = 'FRA';
                
                case 'Moore Males'
                    
                    dataPath = 'Z:\je2527\Analysis\jordan_data\';
                    loadingBar = uiprogressdlg(app.EphysExplorerUIFigure, 'Title', 'Loading data', ...
                        'Message', 'Loading unitInfo...', 'Indeterminate', 'on');
                    drawnow;
                    
                    % Load unitInfo
                    app.unitInfo = importdata([dataPath 'unitInfo.mat']);
                    
                    % Clean up coordinates and cell type name
                    coords = zeros(length(app.unitInfo),3);
                    for row = 1:length(app.unitInfo)
                        coords(row,:) = app.unitInfo(row).xyz;
                        if app.unitInfo(row).cluster(2) == 1
                            app.unitInfo(row).cellType = 'FS';
                        elseif app.unitInfo(row).cluster(2) == 2
                            app.unitInfo(row).cellType = 'RS';
                        elseif app.unitInfo(row).cluster(2) == 3
                            app.unitInfo(row).cellType = 'RS';
                        else
                            app.unitInfo(row).cellType = 'NaN';
                        end
                    end
                    xcoords = num2cell(coords(:,1));
                    ycoords = num2cell(coords(:,2));
                    zcoords = num2cell(coords(:,3)*(-1));
                    [app.unitInfo(:).X] = deal(xcoords{:});
                    [app.unitInfo(:).Y] = deal(ycoords{:});
                    [app.unitInfo(:).Z] = deal(zcoords{:});
                    
                    % Add jitter to points
                    app.jitter = app.jitterRange(1) + (app.jitterRange(2)-app.jitterRange(1)).*rand(length([app.unitInfo.X]),1);                       
                    
                    % Load stimInfo
                    loadingBar.Message = 'Loading stimInfo...';
                    app.stimInfo = importdata([dataPath 'songInfo.mat']);
                        % Clean up stim names
                        for i = 1:length({app.stimInfo.name})
                            seps = strsplit(app.stimInfo(i).name, '_');
                            app.stimInfo(i).name_formatted = [seps{2} ' ' seps{3}];
                        end
                        % Update field names to match mine
                        for j = 1:length(app.stimInfo)
                            padding = zeros(size(app.stimInfo(j).spec, 1), 200); % add 200 ms padding to both ends of spectrogram
                            app.stimInfo(j).spec = [padding app.stimInfo(j).spec padding];
                            app.stimInfo(j).spec = app.stimInfo(j).spec / max(app.stimInfo(j).spec, [], 'all');
                        end
                        [app.stimInfo.S] = app.stimInfo.spec;
                        [app.stimInfo.MPS] = app.stimInfo.mps;
                        [app.stimInfo.FPS] = app.stimInfo.fps;
                        app.stimInfo = rmfield(app.stimInfo, 'spec');
                        app.stimInfo = rmfield(app.stimInfo, 'mps');
                        app.stimInfo = rmfield(app.stimInfo, 'fps');
                    % Load songPSTH
                    loadingBar.Message = 'Loading songPSTH...'; drawnow;
                    app.songPSTH = importdata([dataPath 'songPSTH.mat']);
                    app.songPSTH = app.songPSTH.songPSTH;
                    % Load MRA
                    loadingBar.Message = 'Loading MRA...'; drawnow;
                    app.MRA = importdata([dataPath 'MRA.mat']);
                    app.MRA = app.MRA.MRA;
                    % Load FRA
                    loadingBar.Message = 'Loading FRA...'; drawnow;
                    app.FRA = importdata([dataPath 'FRA.mat']);
                    
                    % Update tuning options for FRA
                    app.NoiseDropDown.Items = {'FRA', 'Population FRA'};
                    app.NoiseDropDown.Value = 'FRA';


                case 'Moore Females'
                    
                    dataPath = 'Z:\jmm2297\JM Data and Plots\Analysis\Moore_Woolley_2019_ephys\females\';
                    loadingBar = uiprogressdlg(app.EphysExplorerUIFigure, 'Title', 'Loading data', ...
                        'Message', 'Loading unitInfo...', 'Indeterminate', 'on');
                    drawnow;
                    
                    % Load unitInfo
                    app.unitInfo = importdata([dataPath 'unitInfo.mat']);
                    
                    % Clean up coordinates and cell type name
                    coords = zeros(length(app.unitInfo),3);
                    for row = 1:length(app.unitInfo)
                        coords(row,:) = app.unitInfo(row).xyz;
                        if app.unitInfo(row).cluster(2) == 1
                            app.unitInfo(row).cellType = 'FS';
                        elseif app.unitInfo(row).cluster(2) == 2
                            app.unitInfo(row).cellType = 'RS';
                        elseif app.unitInfo(row).cluster(2) == 3
                            app.unitInfo(row).cellType = 'RS';
                        else
                            app.unitInfo(row).cellType = 'NaN';
                        end
                    end
                    xcoords = num2cell(coords(:,1));
                    ycoords = num2cell(coords(:,2));
                    zcoords = num2cell(coords(:,3)*(-1));
                    [app.unitInfo(:).X] = deal(xcoords{:});
                    [app.unitInfo(:).Y] = deal(ycoords{:});
                    [app.unitInfo(:).Z] = deal(zcoords{:});
                    
                    % Add jitter to points
                    app.jitter = app.jitterRange(1) + (app.jitterRange(2)-app.jitterRange(1)).*rand(length([app.unitInfo.X]),1);                       
                    
                    % Load stimInfo
                    loadingBar.Message = 'Loading stimInfo...';
                    app.stimInfo = importdata(['Z:\jmm2297\JM Data and Plots\Analysis\Moore_Woolley_2019_ephys\Stimuli\' 'songInfo.mat']);
                        % Clean up stim names
                        for i = 1:length({app.stimInfo.name})
                            seps = strsplit(app.stimInfo(i).name, '_');
                            app.stimInfo(i).name_formatted = [seps{2} ' ' seps{3}];
                        end
                        % Update field names to match mine
                        for j = 1:length(app.stimInfo)
                            padding = zeros(size(app.stimInfo(j).spec, 1), 200); % add 200 ms padding to both ends of spectrogram
                            app.stimInfo(j).spec = [padding app.stimInfo(j).spec padding];
                            app.stimInfo(j).spec = app.stimInfo(j).spec / max(app.stimInfo(j).spec, [], 'all');
                        end
                        [app.stimInfo.S] = app.stimInfo.spec;
                        [app.stimInfo.MPS] = app.stimInfo.mps;
                        [app.stimInfo.FPS] = app.stimInfo.fps;
                        app.stimInfo = rmfield(app.stimInfo, 'spec');
                        app.stimInfo = rmfield(app.stimInfo, 'mps');
                        app.stimInfo = rmfield(app.stimInfo, 'fps');
                    % Load songPSTH
                    loadingBar.Message = 'Loading songPSTH...'; drawnow;
                    app.songPSTH = importdata([dataPath 'songPSTH.mat']);
                    app.songPSTH = app.songPSTH.songPSTH;
                    % Load MRA
                    loadingBar.Message = 'Loading MRA...'; drawnow;
                    app.MRA = importdata([dataPath 'MRA.mat']);
                    
                    % Update tuning options for FRA
                    app.NoiseDropDown.Items = {'N/A'};
                    app.NoiseDropDown.Value = 'N/A';
            end
            
            % Load Custom Colormaps
            loadingBar.Message = 'Loading custom colormap data...'; drawnow;
            app.cmapPalette = importdata('Z:\je2527\Code\palettes\jmaps.mat');
            
            loadingBar.Message = 'Initiating EphysExplorer...'; drawnow;
                    
            % Set list of available subjects from unitInfo
            app.subjectList = unique({app.unitInfo.birdID});
            subjectFormatted = cell(1,length(app.subjectList));
            for i = 1:length(app.subjectList)
                type = unique({app.unitInfo(strcmp({app.unitInfo.birdID}, app.subjectList{i})).type});
                type = type{1};
                type = upper(type);
                subjectFormatted{i} = [type ' ' app.subjectList{i}];
            end
            [subjectFormatted, sortIdx] = sort(subjectFormatted);
            app.subjectList = app.subjectList(sortIdx);
            app.subjectListBox.Items = subjectFormatted;
            
            % Disable interactivity for all plots except 3D scatter
            disableDefaultInteractivity(app.fpsAxes);
            disableDefaultInteractivity(app.mpsAxes);
            disableDefaultInteractivity(app.mraAxes);
            disableDefaultInteractivity(app.nraAxes);
            disableDefaultInteractivity(app.spectrogramAxes);
            app.spectrogramAxes.Tag = 'specAxes'; % tag the spectrogram for range window callbacks :/
            
            % Initiate selectors, timers, fields, etc
            app.Timer = timer('Period', 0.001, 'TimerFcn', @app.animatePlots, 'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf, 'BusyMode', 'drop');
            app.step = 1;
            app.pointType = app.PointTypeDropDown.Value;
            app.maxPointSize = app.MaxPointSizeEditField.Value;
            app.updateColorMap();
            app.updateStimList();
            app.updateStimSelection();
            app.updatePsthMatrix();
            app.create3Dscatter();
            app.loadRegionSchematic();
            app.updateUnitTable();
            
            app.rangeWinStart = 1;
            app.rangeWinEnd = app.stepLength;
            
            app.RippleResponseLabel.Visible = false;
            app.NoiseResponseLabel.Visible = false;
                    
            close(loadingBar);
            
        end

        % Value changed function: RippleDropDown
        function RippleDropDownValueChanged(app, event)
            value = app.RippleDropDown.Value;
            
            switch value
                case 'MRA'
                    % QUERY TABLE SELECTION
                    % IF EXISTS, PLOT THAT UNIT MRA
                    % ELSE CLEAR AXIS
                case 'Population MRA'
                    % COMPUTE AND PLOT POP MRA
                    units = find(app.unitIdxs);
                    if contains(app.DatasetDropdown.Value, 'Moore')
                        varname = 'drFR';
                        tmfname = 'tmf';
                        smfname = 'smf';
                    elseif contains(app.DatasetDropdown.Value, 'Yeh')
                        varname = 'rsFR';
                        tmfname = 'tmf';
                        smfname = 'smf';
                    else
                        varname = 'RS';
                        tmfname = 'TMF';
                        smfname = 'SMF';
                    end
                    if ~isempty(units)
                        popMRA(length(units)) = struct();
                        for i = 1:length(units)
                            mra = app.MRA(units(i)).(varname);
                            mra = mra - nanmean(mra, 'all') / nanstd(mra, 0, 'all');
                            
                            popMRA(i).mra = mra;
                        end
                        popMRA = cat(3, popMRA.mra);
                        popMRA = nanmean(popMRA, 3);
                        
                        tmf = unique(vertcat(app.MRA.(tmfname)));
                        tmf = tmf(~isnan(tmf))';
                        smf = unique(vertcat(app.MRA.(smfname)));
                        smf = smf(~isnan(smf))';
                        
                        if ~all(isnan(popMRA))
                            imagesc(app.mraAxes, tmf, smf, popMRA);
                                app.mraAxes.XTick = [min(tmf) 0 max(tmf)];
                                app.mraAxes.YTick = [min(smf) max(smf)];
                                app.mraAxes.XTickLabel = [min(tmf) 0 max(tmf)];
                                app.mraAxes.YTickLabel = [min(smf) max(smf)];
                                app.mraAxes.XLim = [min(tmf) max(tmf)];
                                app.mraAxes.YLim = [min(smf) max(smf)];
                                switch app.ModeDropDown.Value
                                    case 'light'
                                        app.mraAxes.XColor = 'k';
                                        app.mraAxes.YColor = 'k';
                                    case 'dark'
                                        app.mraAxes.XColor = [0.65 0.65 0.65];
                                        app.mraAxes.YColor = [0.65 0.65 0.65];
                                end
                                app.mraAxes.XLabel.String = 'Hz';
                                app.mraAxes.YLabel.String = 'cyc/kHz';
                                app.mraAxes.FontSize = 10;
                                app.mraAxes.Layer = 'top';
                                app.mraAxes.YDir = 'normal';
                             app.RippleResponseLabel.Visible = false;
                        else
                            cla(app.mraAxes);
                                app.mraAxes.XColor = 'none';
                                app.mraAxes.YColor = 'none';
                        end
                    else
                        cla(app.mraAxes);
                            app.mraAxes.XColor = 'none';
                            app.mraAxes.YColor = 'none';
                    end
            end
        end

        % Value changed function: FSIconDropDown
        function FSIconDropDownValueChanged(app, event)
            app.update3Dscatter();
            
        end

        % Value changed function: RSIconDropDown
        function RSIconDropDownValueChanged(app, event)
            app.update3Dscatter();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EphysExplorerUIFigure = uifigure('Visible', 'off');
            app.EphysExplorerUIFigure.Name = 'Ephys Explorer';
            app.spectrogramSlider = uislider(app.EphysExplorerUIFigure);
            app.spectrogramSlider.Limits = [0 5400];
            app.spectrogramSlider.MajorTicks = [];
            app.spectrogramSlider.ValueChangedFcn = createCallbackFcn(app, @spectrogramSliderValueChanged, true);
            app.spectrogramSlider.ValueChangingFcn = createCallbackFcn(app, @spectrogramSliderValueChanging, true);
            app.spectrogramSlider.MinorTicks = [];
            app.spectrogramSlider.Value = 1;
            app.regionSelectorPanel = uipanel(app.EphysExplorerUIFigure);
            app.regionSelectorPanel.Title = 'Regions';
            app.regionAllButton = uibutton(app.regionSelectorPanel, 'push');
            app.regionAllButton.ButtonPushedFcn = createCallbackFcn(app, @regionAllButtonPushed, true);
            app.regionAllButton.Text = 'All';
            app.regionClearButton = uibutton(app.regionSelectorPanel, 'push');
            app.regionClearButton.ButtonPushedFcn = createCallbackFcn(app, @regionClearButtonPushed, true);
            app.regionClearButton.Text = 'Clear';
            app.regionFilterButton = uibutton(app.regionSelectorPanel, 'push');
            app.regionFilterButton.ButtonPushedFcn = createCallbackFcn(app, @regionFilterButtonPushed, true);
            app.regionFilterButton.Text = 'Filter';
            app.box_L2a = uicheckbox(app.regionSelectorPanel);
            app.box_L2a.Text = 'L2a';
            app.box_L2a.Value = true;
            app.box_L2b = uicheckbox(app.regionSelectorPanel);
            app.box_L2b.Text = 'L2b';
            app.box_L2b.Value = true;
            app.box_L = uicheckbox(app.regionSelectorPanel);
            app.box_L.Text = 'L';
            app.box_L.Value = true;
            app.box_L3 = uicheckbox(app.regionSelectorPanel);
            app.box_L3.Text = 'L3';
            app.box_L3.Value = true;
            app.box_L1 = uicheckbox(app.regionSelectorPanel);
            app.box_L1.Text = 'L1';
            app.box_L1.Value = true;
            app.box_CSt = uicheckbox(app.regionSelectorPanel);
            app.box_CSt.Text = 'CSt';
            app.box_CSt.Value = true;
            app.box_NCM = uicheckbox(app.regionSelectorPanel);
            app.box_NCM.Text = 'NCM';
            app.box_NCM.Value = true;
            app.box_NCL = uicheckbox(app.regionSelectorPanel);
            app.box_NCL.Text = 'NCL';
            app.box_NCL.Value = true;
            app.box_CMM = uicheckbox(app.regionSelectorPanel);
            app.box_CMM.Text = 'CMM';
            app.box_CMM.Value = true;
            app.box_CLM = uicheckbox(app.regionSelectorPanel);
            app.box_CLM.Text = 'CLM';
            app.box_CLM.Value = true;
            app.box_HVC = uicheckbox(app.regionSelectorPanel);
            app.box_HVC.Text = 'HVC';
            app.box_HVC.Value = true;
            app.ShowRegionsButton = uibutton(app.regionSelectorPanel, 'state');
            app.ShowRegionsButton.ValueChangedFcn = createCallbackFcn(app, @ShowRegionsButtonPushed, true);
            app.ShowRegionsButton.Text = {'Show'; 'Regions'};
            app.FocusPlusButton = uibutton(app.regionSelectorPanel, 'push');
            app.FocusPlusButton.ButtonPushedFcn = createCallbackFcn(app, @FocusPlusButtonPushed, true);
            app.FocusPlusButton.Enable = 'off';
            app.FocusPlusButton.Text = '+';
            app.FocusMinusButton = uibutton(app.regionSelectorPanel, 'push');
            app.FocusMinusButton.ButtonPushedFcn = createCallbackFcn(app, @FocusMinusButtonPushed, true);
            app.FocusMinusButton.Enable = 'off';
            app.FocusMinusButton.Text = '-';
            app.FocusCoordsLabel = uilabel(app.regionSelectorPanel);
            app.FocusCoordsLabel.Enable = 'off';
            app.FocusCoordsLabel.Text = 'Coords';
            app.FocusButton = uibutton(app.regionSelectorPanel, 'state');
            app.FocusButton.ValueChangedFcn = createCallbackFcn(app, @FocusButtonValueChanged, true);
            app.FocusButton.Enable = 'off';
            app.FocusButton.Text = 'Focus';
            app.StimulusPanel = uipanel(app.EphysExplorerUIFigure);
            app.StimulusPanel.Title = 'Stimulus';
            app.stimulusDropdown = uidropdown(app.StimulusPanel);
            app.stimulusDropdown.ValueChangedFcn = createCallbackFcn(app, @stimulusDropdownValueChanged, true);
            app.subjectsPanel = uipanel(app.EphysExplorerUIFigure);
            app.subjectsPanel.Title = 'Subjects';
            app.subjectListBox = uilistbox(app.subjectsPanel);
            app.subjectListBox.Items = {'Item 1', 'Item 2', 'Item 3'};
            app.subjectListBox.Multiselect = 'on';
            app.subjectListBox.ValueChangedFcn = createCallbackFcn(app, @subjectListBoxValueChanged, true);
            app.subjectListBox.Value = {'Item 1'};
            app.cellTypeSelectorPanel = uipanel(app.EphysExplorerUIFigure);
            app.cellTypeSelectorPanel.Title = 'Cell Types';
            app.FastSpikersCheckBox = uicheckbox(app.cellTypeSelectorPanel);
            app.FastSpikersCheckBox.ValueChangedFcn = createCallbackFcn(app, @FastSpikersCheckBoxValueChanged, true);
            app.FastSpikersCheckBox.Text = 'Fast Spikers';
            app.FastSpikersCheckBox.Value = true;
            app.RegSpikersCheckBox = uicheckbox(app.cellTypeSelectorPanel);
            app.RegSpikersCheckBox.ValueChangedFcn = createCallbackFcn(app, @RegSpikersCheckBoxValueChanged, true);
            app.RegSpikersCheckBox.Text = 'Reg. Spikers';
            app.RegSpikersCheckBox.Value = true;
            app.FSIconDropDown = uidropdown(app.cellTypeSelectorPanel);
            app.FSIconDropDown.Items = {'circles', 'squares', 'triangles'};
            app.FSIconDropDown.ValueChangedFcn = createCallbackFcn(app, @FSIconDropDownValueChanged, true);
            app.FSIconDropDown.Value = 'circles';
            app.RSIconDropDown = uidropdown(app.cellTypeSelectorPanel);
            app.RSIconDropDown.Items = {'circles', 'squares', 'triangles'};
            app.RSIconDropDown.ValueChangedFcn = createCallbackFcn(app, @RSIconDropDownValueChanged, true);
            app.RSIconDropDown.Value = 'circles';
            app.DisplayOptionsPanel = uipanel(app.EphysExplorerUIFigure);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.PlaybackMultiplierLabel = uilabel(app.DisplayOptionsPanel);
            app.PlaybackMultiplierLabel.Text = 'Playback Multiplier';
            app.playbackMultiplierField = uieditfield(app.DisplayOptionsPanel, 'numeric');
            app.playbackMultiplierField.Limits = [1 100];
            app.playbackMultiplierField.RoundFractionalValues = 'on';
            app.playbackMultiplierField.ValueDisplayFormat = '%.0f';
            app.playbackMultiplierField.Value = 4;
            app.ColorMapLabel = uilabel(app.DisplayOptionsPanel);
            app.ColorMapLabel.Text = 'Color Map';
            app.colormapDropDown = uidropdown(app.DisplayOptionsPanel);
            app.colormapDropDown.Items = {'viridis', 'bone', 'inferno', 'turbo', 'hot'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'viridis';
            app.PointTypeDropDownLabel = uilabel(app.DisplayOptionsPanel);
            app.PointTypeDropDownLabel.Text = 'Point Type';
            app.PointTypeDropDown = uidropdown(app.DisplayOptionsPanel);
            app.PointTypeDropDown.Items = {'color', 'size'};
            app.PointTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PointTypeDropDownValueChanged, true);
            app.PointTypeDropDown.Value = 'color';
            app.ModeDropDownLabel = uilabel(app.DisplayOptionsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.DisplayOptionsPanel);
            app.ModeDropDown.Items = {'light', 'dark'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'light';
            app.MaxPointSizeEditFieldLabel = uilabel(app.DisplayOptionsPanel);
            app.MaxPointSizeEditFieldLabel.Text = 'Max Point Size';
            app.MaxPointSizeEditField = uieditfield(app.DisplayOptionsPanel, 'numeric');
            app.MaxPointSizeEditField.Limits = [1 1000];
            app.MaxPointSizeEditField.RoundFractionalValues = 'on';
            app.MaxPointSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxPointSizeEditFieldValueChanged, true);
            app.MaxPointSizeEditField.Value = 120;
            app.rippleTuningPanel = uipanel(app.EphysExplorerUIFigure);
            app.rippleTuningPanel.Title = 'Single Neuron Metrics';
            app.RippleDropDown = uidropdown(app.rippleTuningPanel);
            app.RippleDropDown.Items = {'MRA', 'Population MRA'};
            app.RippleDropDown.ValueChangedFcn = createCallbackFcn(app, @RippleDropDownValueChanged, true);
            app.RippleDropDown.Value = 'MRA';
            app.NoiseDropDown = uidropdown(app.rippleTuningPanel);
            app.NoiseDropDown.Items = {'NRA', 'Population NRA'};
            app.NoiseDropDown.Value = 'NRA';
            app.SongDropDown = uidropdown(app.rippleTuningPanel);
            app.SongDropDown.Items = {'Song Firing Rate', 'MRA-MPS Overlap'};
            app.SongDropDown.Value = 'Song Firing Rate';
            app.UnitDropDown = uidropdown(app.rippleTuningPanel);
            app.UnitDropDown.Items = {'AP Waveform', 'Reliability'};
            app.UnitDropDown.Value = 'AP Waveform';
            app.RippleResponseLabel = uilabel(app.rippleTuningPanel);
            app.RippleResponseLabel.Text = {'Not'; 'Responsive'};
            app.NoiseResponseLabel = uilabel(app.rippleTuningPanel);
            app.NoiseResponseLabel.Text = {'Not'; 'Responsive'};
            app.mraAxes = uiaxes(app.rippleTuningPanel);
            app.mraAxes.XTick = [];
            app.mraAxes.YTick = [];
            app.nraAxes = uiaxes(app.rippleTuningPanel);
            app.nraAxes.XTick = [];
            app.nraAxes.YTick = [];
            app.apAxes = uiaxes(app.rippleTuningPanel);
            app.apAxes.XTick = [];
            app.apAxes.YTick = [];
            app.DisplayTuningMetricsPanel = uipanel(app.EphysExplorerUIFigure);
            app.DisplayTuningMetricsPanel.Title = 'Display Tuning Metrics';
            app.TuningMetricsDropDown = uidropdown(app.DisplayTuningMetricsPanel);
            app.TuningMetricsDropDown.Items = {'% Ripple Responsive', 'Ripple Selectivity Index', 'Upsweep Selectivity', 'Downsweep Selectivity', '% Tracking Area'};
            app.TuningMetricsDropDown.Value = '% Ripple Responsive';
            app.PlotTuningButton = uibutton(app.DisplayTuningMetricsPanel, 'push');
            app.PlotTuningButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTuningButtonPushed, true);
            app.PlotTuningButton.Text = 'Plot';
            app.AnimatePSTHPanel = uipanel(app.EphysExplorerUIFigure);
            app.AnimatePSTHPanel.Title = 'Animate PSTH';
            app.ResetButton = uibutton(app.AnimatePSTHPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PlayButton = uibutton(app.AnimatePSTHPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.UnitTable = uitable(app.EphysExplorerUIFigure);
            app.UnitTable.ColumnName = {'Bird'; 'Blk'; 'Chn'; 'Unit'; 'Reg'; 'Type'};
            app.UnitTable.RowName = {''};
            app.UnitTable.ColumnSortable = [true false false false true true];
            app.UnitTable.CellSelectionCallback = createCallbackFcn(app, @UnitTableCellSelection, true);
            app.ExportVideoPanel = uipanel(app.EphysExplorerUIFigure);
            app.ExportVideoPanel.Title = 'Export Video';
            app.WriteFramesButton = uibutton(app.ExportVideoPanel, 'push');
            app.WriteFramesButton.ButtonPushedFcn = createCallbackFcn(app, @WriteFramesButtonPushed, true);
            app.WriteFramesButton.Text = 'Write Frames';
            app.FPSEditFieldLabel = uilabel(app.ExportVideoPanel);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.ExportVideoPanel, 'numeric');
            app.FPSEditField.Limits = [1 1000];
            app.FPSEditField.Value = 24;
            app.StitchVideoButton = uibutton(app.ExportVideoPanel, 'push');
            app.StitchVideoButton.ButtonPushedFcn = createCallbackFcn(app, @StitchVideoButtonPushed, true);
            app.StitchVideoButton.Text = 'Stitch Video';
            app.TogglePeaksButton = uibutton(app.EphysExplorerUIFigure, 'state');
            app.TogglePeaksButton.ValueChangedFcn = createCallbackFcn(app, @TogglePeaksButtonValueChanged, true);
            app.TogglePeaksButton.Text = 'Show Peaks';
            app.TogglePPSTHButton = uibutton(app.EphysExplorerUIFigure, 'state');
            app.TogglePPSTHButton.ValueChangedFcn = createCallbackFcn(app, @TogglePPSTHButtonValueChanged, true);
            app.TogglePPSTHButton.Text = 'Show popPSTH';
            app.LoadDatasetPanel = uipanel(app.EphysExplorerUIFigure);
            app.LoadDatasetPanel.Title = 'Load Dataset';
            app.DatasetDropdown = uidropdown(app.LoadDatasetPanel);
            app.DatasetDropdown.Items = {'Edwards Multispecies', 'Yeh Striatum', 'Moore Males', 'Moore Females'};
            app.DatasetDropdown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropdownValueChanged, true);
            app.DatasetDropdown.Value = 'Edwards Multispecies';
            app.animatedAxes = uiaxes(app.EphysExplorerUIFigure);
            app.mpsAxes = uiaxes(app.EphysExplorerUIFigure);
            app.mpsAxes.XTick = [];
            app.mpsAxes.YTick = [];
            app.spectrogramAxes = uiaxes(app.EphysExplorerUIFigure);
            app.spectrogramAxes.XTick = [];
            app.spectrogramAxes.YTick = [];
            app.spectrogramAxes.ZTick = [];
            app.spectrogramAxes.ButtonDownFcn = createCallbackFcn(app, @spectrogramAxesButtonDown, true);
            app.fpsAxes = uiaxes(app.EphysExplorerUIFigure);
            xlabel(app.fpsAxes, 'Freq')
            app.fpsAxes.YLim = [0 1];
            app.fpsAxes.XTick = [];
            app.fpsAxes.YTick = [];
            % Show the figure after all components are created
            app.EphysExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ephysExplorer2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EphysExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EphysExplorerUIFigure)
        end
    end
end